classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        SpeedofAudioKnob            matlab.ui.control.DiscreteKnob
        SpeedofAudioKnobLabel       matlab.ui.control.Label
        EbN0Label                   matlab.ui.control.Label
        PanelAudio                  matlab.ui.container.Panel
        attachedAudioPreview        matlab.ui.control.UIAxes
        PanelImage                  matlab.ui.container.Panel
        attachedImagePreview        matlab.ui.control.Image
        PanelVideo                  matlab.ui.container.Panel
        attachedVideoPreview        matlab.ui.control.UIAxes
        recorder                    matlab.ui.control.Image
        send                        matlab.ui.control.Image
        EbN0Slider                  matlab.ui.control.Slider
        attachFile                  matlab.ui.control.Image
        TextArea                    matlab.ui.control.TextArea
        ModDemodSchemesButtonGroup  matlab.ui.container.ButtonGroup
        WiMaxNormalEncodingButton   matlab.ui.control.ToggleButton
        GNormalEncodingButton       matlab.ui.control.ToggleButton
        WiMaxHuffmanEncodingButton  matlab.ui.control.ToggleButton
        GHuffmanEncodingButton      matlab.ui.control.ToggleButton
        Panel_2                     matlab.ui.container.Panel
        GMaxHuff                    matlab.ui.container.Panel
        GMaxNormal                  matlab.ui.container.Panel
        GMaxHuffAndNormal           matlab.ui.container.Panel
        TimeComparison              matlab.ui.container.Panel
        GraphComparison             matlab.ui.container.Panel
    end


    properties (Access = public)
        initialPosition_x_attached_file     = 300;
        initialPosition_y_attached_file     = 70;
    end
    
    properties (Access = public)

        guiOption                           = 1;

        initialPosition_received_x          = 10;
        initialPosition_sent_x              = 500;
        
        initialPosition_GMaxHuff_y          = 70;
        initialPosition_GMaxNormal_y        = 70;
        initialPosition_GMaxHuffAndNormal_y = 70;
        initialPosition_TimeComparison_y    = 70;
        initialPosition_GraphComparison_y   = 70;

        audioSpeed                              ;

        video                               = [];
        audio                               = [];
        rate                                = [];
        text                                = {' '};
        image                               = [];
    end
    
    methods (Access = public)
        
        %function getButtonSelected(app)
        function transmittedSignalType = guiGetTransmittedSignalType(app)
        if ~isempty(app.image)
            transmittedSignalType = 2; 
        elseif ~isempty(app.video)
            transmittedSignalType = 4;
        elseif ~isempty(app.audio)
            transmittedSignalType = 3;
        elseif ~isempty(app.text{1,1})
            transmittedSignalType = 1;
        
        end

end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function playAudio(app)

        end

        % Callback function
        function Button4Pushed(app, event)

        end

        % Callback function
        function Image4Clicked(app, event)
            
        end

        % Image clicked function: send
        function returnKeyPressed(app, event)
            clear sound;
            sound(audioread('whatsappSend.mp3'), 44100, 16);
            signalType = guiGetTransmittedSignalType(app);
            switch app.guiOption
                case 1
                    switch signalType
                        case 1 % Text
                            textSent = uilabel(app.GMaxHuff); 
                            textSent.Text = app.TextArea.Value;
                            textSent.Position = [app.initialPosition_sent_x+180 app.initialPosition_GMaxHuff_y 200 size(textSent, 1)*30];
                            textSent.BackgroundColor = [0 0.7 0];
                            app.initialPosition_GMaxHuff_y = app.initialPosition_GMaxHuff_y + 100;
                        case 2 % Image
                            imageSent = uiimage(app.GMaxHuff);
                            imageSent.ImageSource = app.attachedImagePreview.ImageSource;
                            imageSent.Position = [app.initialPosition_sent_x app.initialPosition_GMaxHuff_y size(imageSent.ImageSource, 1) size(imageSent.ImageSource, 2)];
                            app.initialPosition_GMaxHuff_y = app.initialPosition_GMaxHuff_y + size(imageSent.ImageSource, 2);
                        case 3 % Audio
%                             audioSent = uiaxes(app.GMaxHuff);
%                             audioSent.XTickLabel = '';
%                             audioSent.YTickLabel = '';
%                             plot(app.audio, 'Parent', audioSent);
%                             audioSent.Position = [app.initialPosition_sent_x app.initialPosition_GMaxHuff_y 300 80];
%                             app.initialPosition_GMaxHuff_y = app.initialPosition_GMaxHuff_y + 70;

                        case 4 % Video
                                otherwise
                    end
                case 2
                    switch signalType
                        case 1 % Text
                            textSent = uilabel(app.GMaxNormal); 
                            textSent.Text = app.TextArea.Value;
                            textSent.Position = [app.initialPosition_sent_x+180 app.initialPosition_GMaxNormal_y 200 size(textSent, 1)*30];
                            textSent.BackgroundColor = [0 0.7 0];
                            app.initialPosition_GMaxNormal_y = app.initialPosition_GMaxNormal_y + 100;
                        case 2 % Image
                            imageSent = uiimage(app.GMaxNormal);
                            imageSent.ImageSource = app.attachedImagePreview.ImageSource;
                            imageSent.Position = [app.initialPosition_sent_x app.initialPosition_GMaxNormal_y size(imageSent.ImageSource, 1) size(imageSent.ImageSource, 2)];
                            app.initialPosition_GMaxNormal_y = app.initialPosition_GMaxNormal_y + size(imageSent.ImageSource, 2);
                        case 3 % Audio
%                             audioSent = uiaxes(app.GMaxNormal);
%                             audioSent.XTickLabel = '';
%                             audioSent.YTickLabel = '';
%                             plot(app.audio, 'Parent', audioSent);
%                             audioSent.Position = [app.initialPosition_sent_x app.initialPosition_GMaxNormal_y 300 80];
%                             app.initialPosition_GMaxNormal_y = app.initialPosition_GMaxNormal_y + 70;
                        case 4 % Video
                        otherwise
                    end
                case 3
                    switch signalType
                        case 1 % Text
                            textSent = uilabel(app.GMaxHuffAndNormal); 
                            textSent.Text = app.TextArea.Value;
                            textSent.Position = [app.initialPosition_sent_x+180 app.initialPosition_GMaxHuffAndNormal_y 200 size(textSent, 1)*30];
                            textSent.BackgroundColor = [0 0.7 0];
                            app.initialPosition_GMaxHuffAndNormal_y = app.initialPosition_GMaxHuffAndNormal_y + 100;
                        case 2 % Image
                            imageSent = uiimage(app.GMaxHuffAndNormal);
                            imageSent.ImageSource = app.attachedImagePreview.ImageSource;
                            imageSent.Position = [app.initialPosition_sent_x app.initialPosition_GMaxHuffAndNormal_y size(imageSent.ImageSource, 1) size(imageSent.ImageSource, 2)];
                            app.initialPosition_GMaxHuffAndNormal_y = app.initialPosition_GMaxHuffAndNormal_y + size(imageSent.ImageSource, 2);
                        case 3 % Audio
                            audioSent = uiaxes(app.GMaxHuffAndNormal);
                            audioSent.XTickLabel = '';
                            audioSent.YTickLabel = '';
                            plot(app.audio, 'Parent', audioSent);
                            audioSent.Position = [app.initialPosition_sent_x app.initialPosition_GMaxHuffAndNormal_y 300 100];
                            app.initialPosition_GMaxHuffAndNormal_y = app.initialPosition_GMaxHuffAndNormal_y + 70;
                        case 4 % Video
                                otherwise
                    end
                    
                case 4
                    switch signalType
                        case 1 % Text
                            textSent = uilabel(app.TimeComparison); 
                            textSent.Text = app.TextArea.Value;
                            textSent.Position = [app.initialPosition_sent_x+180 app.initialPosition_TimeComparison_y 200 size(textSent, 1)*30];
                            textSent.BackgroundColor = [0 0.7 0];
                            app.initialPosition_TimeComparison_y = app.initialPosition_TimeComparison_y + 100;
                        case 2 % Image
                            imageSent = uiimage(app.TimeComparison);
                            imageSent.ImageSource = app.attachedImagePreview.ImageSource;
                            imageSent.Position = [app.initialPosition_sent_x app.initialPosition_TimeComparison_y size(imageSent.ImageSource, 1) size(imageSent.ImageSource, 2)];
                            app.initialPosition_TimeComparison_y = app.initialPosition_TimeComparison_y + size(imageSent.ImageSource, 2);
                        case 3 % Audio
                            audioSent = uiaxes(app.TimeComparison);
                            audioSent.XTickLabel = '';
                            audioSent.YTickLabel = '';
                            plot(app.audio, 'Parent', audioSent);
                            audioSent.Position = [app.initialPosition_sent_x app.initialPosition_TimeComparison_y 300 100];
                            app.initialPosition_TimeComparison_y = app.initialPosition_TimeComparison_y + 70;
                        case 4 % Video
                                otherwise
                    end
                case 5

            end
                                

            ModDemodSchemesButtonGroupSelectionChanged(app);
            assignin('base', 'video',        app.video);
            assignin('base', 'audio',        app.audio);
            assignin('base', 'sampleRateFs', app.rate);
            assignin('base', 'image',        app.attachedImagePreview.ImageSource);
            assignin('base', 'text' ,        app.TextArea.Value);


            set(app.send, 'Enable', 'off');

            app.attachedImagePreview.ImageSource = '';
            set(app.PanelVideo, 'Visible', 'off');
            set(app.attachedVideoPreview, 'Visible', 'off');
            set(app.PanelImage, 'Visible', 'off');
            set(app.attachedImagePreview, 'Visible', 'off');
            set(app.PanelAudio, 'Visible', 'off');
            set(app.attachedVideoPreview, 'Visible', 'off');
            app.TextArea.Value = '';
        end

        % Callback function
        function TextAreaValueChanged(app, event)

        end

        % Value changing function: TextArea
        function TextAreaValueChanging(app, event)

        end

        % Image clicked function: attachFile
        function attachFileClicked(app, event)
            [baseFileName, folder] = uigetfile({'*.png';'*.jpg';'*.JPG';'*.avi'});
            if isequal(baseFileName, 0)
                disp('Canceled')
            else
                fullFileName = fullfile(folder, baseFileName);
                if(baseFileName(1, end-2:end) == 'avi')
                    %originalVideo = uiaxes(app.UIFigure);
                    % originalVideo = app.attachedVideoPreview;
                    % originalVideo.Position = [app.Panel_2.Position(1, 1) app.initialPosition_y_attached_file 402 405];
                    numberOfBits = 24;
                    [sampledAudio, sampleRateFs] = audioread(fullFileName);
                    vidObj = VideoReader(fullFileName);
                    sound(sampledAudio, sampleRateFs*app.SpeedofAudioKnob.Value, numberOfBits);
                    

                    app.audio        = sampledAudio;
                    app.rate         = sampleRateFs;
                    set(app.PanelVideo, 'Visible', 'on');
                    set(app.attachedVideoPreview, 'Visible', 'on');
                    
                    while(hasFrame(vidObj))
                        frame = readFrame(vidObj);
                        imshow(frame, 'Parent', app.attachedVideoPreview);   % <--- use the Parent property
                        pause(2/vidObj.FrameRate);
                    end
                    app.video = fullFileName;
                else
%                     originalImage = uiimage(app.UIFigure);
%                     originalImage.Position = [app.Panel_2.Position(1, 1) app.initialPosition_y_attached_file 402 405];
%                     app.initialPosition_y_attached_file = app.initialPosition_y_attached_file + originalImage.Position(1, 4);
                    app.attachedImagePreview.ImageSource = imresize(imread(fullFileName), [402, 405]);
                    app.image = app.attachedImagePreview.ImageSource;
                    set(app.PanelImage, 'Visible', 'on');
                    set(app.attachedImagePreview, 'Visible', 'on');
                    % assignin('base', 'image', originalImage.ImageSource);
                end
            end
        end

        % Image clicked function: recorder
        function recorderImageClicked(app, event)
            [baseAudioFileName, audioFolder] = uigetfile({'*.mp3'; '*.wav'; '*.avi'});
            if isequal(baseAudioFileName, 0)
                disp('Canceled')
            else
                fullFileName = fullfile(audioFolder, baseAudioFileName);
                numberOfBits = 24;
                [sampledAudio, sampleRateFs] = audioread(fullFileName);
                plot(sampledAudio, 'Parent', app.attachedAudioPreview);   % <--- use the Parent property
                set(app.PanelAudio, 'Visible', 'on');
                set(app.attachedAudioPreview, 'Visible', 'on');
                sound(sampledAudio, sampleRateFs*app.SpeedofAudioKnob.Value, numberOfBits);

                %sound(sampledAudio, sampleRateFs, numberOfBits);
                app.audio = sampledAudio;
                app.rate = sampleRateFs;
            end
        end

        % Selection changed function: ModDemodSchemesButtonGroup
        function ModDemodSchemesButtonGroupSelectionChanged(app, event)
            selectedButton = app.ModDemodSchemesButtonGroup.SelectedObject;
            switch selectedButton
                case app.GHuffmanEncodingButton
                    set(app.GMaxHuff            ,'Visible', 'on');
                    set(app.GMaxNormal          ,'Visible', 'off');
                    set(app.GMaxHuffAndNormal   ,'Visible', 'off');
                    set(app.TimeComparison      ,'Visible', 'off');
                    set(app.GraphComparison     ,'Visible', 'off');
                    assignin('base', 'option', 1);
                    app.guiOption = 1;
                    app.recorder.Visible = 'off';
                    app.EbN0Slider.Visible = 'off';
                    app.EbN0Label.Visible = 'off';
                case app.WiMaxHuffmanEncodingButton
                    set(app.GMaxHuff            ,'Visible', 'off');
                    set(app.GMaxNormal          ,'Visible', 'on');
                    set(app.GMaxHuffAndNormal   ,'Visible', 'off');
                    set(app.TimeComparison      ,'Visible', 'off');
                    set(app.GraphComparison     ,'Visible', 'off');
                    assignin('base', 'option', 2);
                    app.guiOption = 2;
                    app.recorder.Visible = 'off';
                    app.EbN0Slider.Visible = 'off';
                    app.EbN0Label.Visible = 'off';
                case app.GNormalEncodingButton
                    set(app.GMaxHuff            ,'Visible', 'off');
                    set(app.GMaxNormal          ,'Visible', 'off');
                    set(app.GMaxHuffAndNormal   ,'Visible', 'on');
                    set(app.TimeComparison      ,'Visible', 'off');
                    set(app.GraphComparison     ,'Visible', 'off');
                    assignin('base', 'option', 3);
                    app.guiOption = 3;
                    app.recorder.Visible = 'on';
                    app.EbN0Slider.Visible = 'on';
                    app.EbN0Label.Visible = 'on';
                case app.WiMaxNormalEncodingButton
                    set(app.GMaxHuff            ,'Visible', 'off');
                    set(app.GMaxNormal          ,'Visible', 'off');
                    set(app.GMaxHuffAndNormal   ,'Visible', 'off');
                    set(app.TimeComparison      ,'Visible', 'on');
                    set(app.GraphComparison     ,'Visible', 'off');
                    assignin('base', 'option', 4);
                    app.guiOption = 4;
                    app.recorder.Visible = 'on';
                    app.EbN0Slider.Visible = 'on';
                    app.EbN0Label.Visible = 'on';
                case app.Comparisonbetween5GWiMaxGraphsNormalEncodingButton
                    set(app.GMaxHuff            ,'Visible', 'off');
                    set(app.GMaxNormal          ,'Visible', 'off');
                    set(app.GMaxHuffAndNormal   ,'Visible', 'off');
                    set(app.TimeComparison      ,'Visible', 'off');
                    set(app.GraphComparison     ,'Visible', 'on');
                    assignin('base', 'option', 5);
                    app.guiOption = 5;
                    app.recorder.Visible = 'on';
                otherwise
                    assignin('base', 'option', 6);
            end
        end

        % Callback function
        function ButtonPushed(app, event)

        end

        % Value changed function: EbN0Slider
        function EbN0SliderValueChanged(app, event)
            value = app.EbN0Slider.Value;
            assignin('base', 'ebno', value);
        end

        % Value changed function: SpeedofAudioKnob
        function SpeedofAudioKnobValueChanged(app, event)
            value = app.SpeedofAudioKnob.Value;
            % assignin('base', 'audioSpeed', value);
            app.audioSpeed = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel_2 = uipanel(app.UIFigure);
            app.GraphComparison = uipanel(app.Panel_2);
            app.TimeComparison = uipanel(app.Panel_2);
            app.GMaxHuffAndNormal = uipanel(app.Panel_2);
            app.GMaxNormal = uipanel(app.Panel_2);
            app.GMaxHuff = uipanel(app.Panel_2);
            app.ModDemodSchemesButtonGroup = uibuttongroup(app.UIFigure);
            app.ModDemodSchemesButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ModDemodSchemesButtonGroupSelectionChanged, true);
            app.ModDemodSchemesButtonGroup.Title = 'Mod/Demod Schemes';
            app.GHuffmanEncodingButton = uitogglebutton(app.ModDemodSchemesButtonGroup);
            app.GHuffmanEncodingButton.Icon = fullfile(pathToMLAPP, 'sign-right-icon.png');
            app.GHuffmanEncodingButton.Text = {'5G'; 'Huffman Encoding'};
            app.GHuffmanEncodingButton.Value = true;
            app.WiMaxHuffmanEncodingButton = uitogglebutton(app.ModDemodSchemesButtonGroup);
            app.WiMaxHuffmanEncodingButton.Icon = fullfile(pathToMLAPP, 'sign-right-icon.png');
            app.WiMaxHuffmanEncodingButton.Text = {'WiMax'; 'Huffman Encoding'};
            app.GNormalEncodingButton = uitogglebutton(app.ModDemodSchemesButtonGroup);
            app.GNormalEncodingButton.Icon = fullfile(pathToMLAPP, 'sign-right-icon.png');
            app.GNormalEncodingButton.Text = {'5G'; 'Normal Encoding '};
            app.WiMaxNormalEncodingButton = uitogglebutton(app.ModDemodSchemesButtonGroup);
            app.WiMaxNormalEncodingButton.Icon = fullfile(pathToMLAPP, 'sign-right-icon.png');
            app.WiMaxNormalEncodingButton.Text = {'WiMax'; 'Normal Encoding'};
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.ValueChangingFcn = createCallbackFcn(app, @TextAreaValueChanging, true);
            app.attachFile = uiimage(app.UIFigure);
            app.attachFile.ImageClickedFcn = createCallbackFcn(app, @attachFileClicked, true);
            app.attachFile.ImageSource = fullfile(pathToMLAPP, 'attachment.png');
            app.EbN0Slider = uislider(app.UIFigure);
            app.EbN0Slider.Limits = [0 80];
            app.EbN0Slider.ValueChangedFcn = createCallbackFcn(app, @EbN0SliderValueChanged, true);
            app.EbN0Slider.Visible = 'off';
            app.EbN0Slider.Value = 71;
            app.send = uiimage(app.UIFigure);
            app.send.ImageClickedFcn = createCallbackFcn(app, @returnKeyPressed, true);
            app.send.ImageSource = fullfile(pathToMLAPP, 'unnamed.png');
            app.recorder = uiimage(app.UIFigure);
            app.recorder.ImageClickedFcn = createCallbackFcn(app, @recorderImageClicked, true);
            app.recorder.Visible = 'off';
            app.recorder.ImageSource = fullfile(pathToMLAPP, 'micwhite.png');
            app.PanelVideo = uipanel(app.UIFigure);
            app.PanelVideo.Visible = 'off';
            app.attachedVideoPreview = uiaxes(app.PanelVideo);
            title(app.attachedVideoPreview, 'Title')
            xlabel(app.attachedVideoPreview, 'X')
            ylabel(app.attachedVideoPreview, 'Y')
            zlabel(app.attachedVideoPreview, 'Z')
            app.attachedVideoPreview.Visible = 'off';
            app.PanelImage = uipanel(app.UIFigure);
            app.PanelImage.Visible = 'off';
            app.attachedImagePreview = uiimage(app.PanelImage);
            app.attachedImagePreview.Visible = 'off';
            app.PanelAudio = uipanel(app.UIFigure);
            app.PanelAudio.Visible = 'off';
            app.attachedAudioPreview = uiaxes(app.PanelAudio);
            app.attachedAudioPreview.XTickLabel = '';
            app.attachedAudioPreview.YTickLabel = '';
            app.attachedAudioPreview.Visible = 'off';
            app.EbN0Label = uilabel(app.UIFigure);
            app.EbN0Label.Visible = 'off';
            app.EbN0Label.Text = 'Eb/N0';
            app.SpeedofAudioKnobLabel = uilabel(app.UIFigure);
            app.SpeedofAudioKnobLabel.Text = 'Speed of Audio';
            app.SpeedofAudioKnob = uiknob(app.UIFigure, 'discrete');
            app.SpeedofAudioKnob.Items = {'Slow', 'Normal', 'Fast'};
            app.SpeedofAudioKnob.ItemsData = [0.5 1 2];
            app.SpeedofAudioKnob.ValueChangedFcn = createCallbackFcn(app, @SpeedofAudioKnobValueChanged, true);
            app.SpeedofAudioKnob.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @playAudio)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end